classdef FahrtsimulationAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        FahrtsimulationTab              matlab.ui.container.Tab
        LeistungsverlaufButtonGroup     matlab.ui.container.ButtonGroup
        StreckeButton                   matlab.ui.control.RadioButton
        ZeitButton                      matlab.ui.control.RadioButton
        resetPlotsButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EditField                       matlab.ui.control.EditField
        SimulationButton                matlab.ui.control.Button
        StreckeDropDown                 matlab.ui.control.DropDown
        StreckeDropDownLabel            matlab.ui.control.Label
        FahrzeugtypDropDown             matlab.ui.control.DropDown
        FahrzeugtypDropDownLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FahrplansimTab                  matlab.ui.container.Tab
        SpeichereDatenButton            matlab.ui.control.Button
        FahrplanDropDown                matlab.ui.control.DropDown
        FahrplanDropDownLabel           matlab.ui.control.Label
        BelegungButtonGroup             matlab.ui.container.ButtonGroup
        DreifachButton                  matlab.ui.control.RadioButton
        ZweifachButton                  matlab.ui.control.RadioButton
        EinfachButton                   matlab.ui.control.RadioButton
        TabGroup2                       matlab.ui.container.TabGroup
        LeistungsverlaufTab             matlab.ui.container.Tab
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        MittelwerteTab                  matlab.ui.container.Tab
        MesswerteSonntagCheckBox        matlab.ui.control.CheckBox
        MesswerteMontagCheckBox         matlab.ui.control.CheckBox
        MittelwertpositiveWerteCheckBox  matlab.ui.control.CheckBox
        MittelwertCheckBox              matlab.ui.control.CheckBox
        UIAxes4                         matlab.ui.control.UIAxes
        WiderstndeTab                   matlab.ui.container.Tab
        PlotWiderstndeButton            matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        SimDataTab                      matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        BenutzerdefTab                  matlab.ui.container.Tab
        YAchseButtonGroup               matlab.ui.container.ButtonGroup
        RechtsButton                    matlab.ui.control.RadioButton
        LinksButton                     matlab.ui.control.RadioButton
        Tree                            matlab.ui.container.Tree
        FahrzeugparameterNode           matlab.ui.container.TreeNode
        GeschwindigkeitNode             matlab.ui.container.TreeNode
        BeschleunigungNode              matlab.ui.container.TreeNode
        ZugkraftNode                    matlab.ui.container.TreeNode
        LeistungNode                    matlab.ui.container.TreeNode
        AdhaesionskraftNode             matlab.ui.container.TreeNode
        StreckenparameterNode           matlab.ui.container.TreeNode
        StreckeNode                     matlab.ui.container.TreeNode
        ReststreckeNode                 matlab.ui.container.TreeNode
        BremsstreckeNode                matlab.ui.container.TreeNode
        SteigungNode                    matlab.ui.container.TreeNode
        MaximalGeschwNode               matlab.ui.container.TreeNode
        LaufwiderstandNode              matlab.ui.container.TreeNode
        SteigungswiderstandNode         matlab.ui.container.TreeNode
        BeschleunigungswiderstandNode   matlab.ui.container.TreeNode
        GesamtwiderstandNode            matlab.ui.container.TreeNode
        ResetPlotButton                 matlab.ui.control.Button
        XAchseButtonGroup               matlab.ui.container.ButtonGroup
        GeschwindigkeitButton           matlab.ui.control.RadioButton
        StreckeButton_2                 matlab.ui.control.RadioButton
        ZeitButton_2                    matlab.ui.control.RadioButton
        UIAxes6                         matlab.ui.control.UIAxes
        VergleichSonntagMontagTab       matlab.ui.container.Tab
        VergleichSonntagundMontagButton  matlab.ui.control.Button
        UIAxes7                         matlab.ui.control.UIAxes
        letztesFahrzeugEditField        matlab.ui.control.NumericEditField
        bisLabel                        matlab.ui.control.Label
        SimFahrzeugeEditField           matlab.ui.control.NumericEditField
        SimFahrzeugeEditFieldLabel      matlab.ui.control.Label
        FahrzeugNrEditField             matlab.ui.control.NumericEditField
        FahrzeugNrEditFieldLabel        matlab.ui.control.Label
        StreckendatenDropDown           matlab.ui.control.DropDown
        StreckendatenDropDownLabel      matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EndTimeTextArea                 matlab.ui.control.TextArea
        EndTimeTextAreaLabel            matlab.ui.control.Label
        StartTimeTextArea               matlab.ui.control.TextArea
        StartTimeTextAreaLabel          matlab.ui.control.Label
        FahrzeugtypDropDown_2           matlab.ui.control.DropDown
        FahrzeugtypDropDown_2Label      matlab.ui.control.Label
        SimulationLamp                  matlab.ui.control.Lamp
        StartFahrplansimultationButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CompData; % Description
        CompleteData;
        P_tot;
        timeP_tot;
        start;
        finish;
        time;
        legendUI4 = strings(1,4);
        legendUI4Nr = 1;
        FahrzeugName;
        legendUI6 = struct("left", strings(1,1), "right", strings(1,1));
        legendUI6Nrright = 1;
        legendUI6Nrleft = 1;
        units = struct("Geschwindigkeit", "[km/h]", "Beschleunigung", "[m/s^2]", "Zugkraft", "[kN]", "Leistung", "[kW]", "Adhaesionskraft", "[kN]", "Strecke", "[m]", "Reststrecke", ...
            "[m]", "Bremsstrecke", "[m]", "Steigung", "[‰]", "MaximalGeschw", "[km/h]", "Laufwiderstand", "[kN]", "Steigungswiderstand", "[kN]", ...
            "Beschleunigungswiderstand", "[kN]", "Gesamtwiderstand", "[kN]");
        SimData;
        Leistungsverlauf;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SimulationButton, UITable
        function SimulationButtonPushed(app, event)
            % Importieren und ausführen der benötigten Skripte
            run Streckenparameter2.mlx;
            run Import_track_ZLR_Data.m;

            % Variable für die Auswahl des Fahrzeugtyps im Drop Down Menu
            FZDropDown = app.FahrzeugtypDropDown;

            % Auswahl des Fahrzeugtyps
            if (FZDropDown.Value == "KISS")
                FZ = KISS;
                FZ.mass = 2 * FZ.mass;
            elseif (FZDropDown.Value == "RE450")
                FZ = RE450;
            else
                FZ = 0;
            end

            % Variable für die Auswahl der Strecke im Drop Down Menu
            StrDropDown = app.StreckeDropDown;

            % Auswahl der gewählten Strecke
            if(StrDropDown.Value == "ZH_HB")
                Str = ZH_HB;
            elseif (StrDropDown.Value == "HB_ZH")
                Str = HB_ZH;
            elseif (StrDropDown.Value == "ST_ZH")
                Str = ST_ZH;
            elseif (StrDropDown.Value == "ZH_ST")
                Str = ZH_ST;
            else
                Str = 0;
            end

            % [FZ] = StreckeUndFahrzeug(app.FahrzeugtypDropDown);
        
            % Durchführung der Simulation
            [Str_sim, Fz_sim, CompData] = Fahrtsimulation6_3(Str, FZ, const);
            % Berechnung der Zeit
            time = seconds(0:0.1:length(Str_sim.Strecke)/10-0.1);
            
            % Plotten der Simulation mit xAchse = Strecke
            if(app.StreckeButton.Value == 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, CompData.Strecke, CompData.Leistung/1000)
                title(app.UIAxes, 'Leistungsverlauf Fahrtsimulation')
                xlabel(app.UIAxes, 'Strecke [m]')
                hold(app.UIAxes, 'off')

            % Plotten der Simulation mit xAchse = Zeit
            elseif(app.ZeitButton.Value == 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, time, CompData.Leistung/1000)
                title(app.UIAxes, 'Leistungsverlauf Fahrtsimulation')
                xlabel(app.UIAxes, 'Zeit [s]')
                hold(app.UIAxes, 'off')

            end
            
            % Plotten der Geschwindigkeit im Verlauf der Strecke
            plot(app.UIAxes2, CompData.Strecke, CompData.Geschwindigkeit)
            title(app.UIAxes2, 'Geschwindigkeitsverlauf')
            xlabel(app.UIAxes2, 'Strecke [m]')
            ylabel(app.UIAxes2, 'Geschwindigkeit [m/s]')

            % Ausgabe der Streckendaten
            t = table((Str.stations(2,1)-Str.stations(1,1)),Str.speedLimits(1,2), min(Str.gradients(:,2)), max(Str.gradients(:,2)), 'VariableNames',{'Länge','V_max', 'Min', 'Max'});
            app.UITable.Data = t;       
            
        end

        % Button pushed function: resetPlotsButton
        function resetPlotsButtonPushed(app, event)
            % Zürücksetzen der Plots
            cla(app.UIAxes)
            cla(app.UIAxes2)
        end

        % Selection changed function: LeistungsverlaufButtonGroup
        function LeistungsverlaufButtonGroupSelectionChanged(app, event)
            selectedButton = app.LeistungsverlaufButtonGroup.SelectedObject;
            % Auswahl ob der Leistungsverlauf über die Zeit oder über die
            % Strecke geplottet werden soll.
            cla(app.UIAxes)
        end

        % Button pushed function: StartFahrplansimultationButton
        function StartFahrplansimultationButtonPushed(app, event)
            % solange Simulation läuft leuchtet die Anzeige Rot
            app.SimulationLamp.Color = 'r';
            app.EditField_2.Value = 'Simulation läuft';
            % bei jeder Simulation wird der Plot zurückgesetzt.
            cla(app.UIAxes3)
            pause(0.5)
            
            % Auswahl des Fahrplans (kann bearbeitet werden um weitere
            % Fahrpläne hinzuzufügen).
            if(app.FahrplanDropDown.Value == "Montag")
                run Import_timetable.mlx;
            elseif(app.FahrplanDropDown.Value == "Sonntag")
                run Import_timetableSunday.mlx;
                Timetable = TimetableSunday;
            end

            run Streckenparameter2.mlx;
            run Import_track_ZLR_Data.m;

            % erstellen einer Matrix, in welcher die Daten gespeichert werden.
            sim_data = zeros(864000, length(Timetable.Start)+1);
            sim_data(:,1) = 0.1:0.1:86400;

            % Auswahl des Fahrzeugs
            if (app.FahrzeugtypDropDown_2.Value == "KISS")
                FZ = KISS;
            elseif (app.FahrzeugtypDropDown_2.Value == "RE450")
                FZ = RE450;
            else
                FZ = 0;
            end

            % Belegung des Fahrzeugs:
            if(app.EinfachButton.Value == 1)
                FZ.mass = FZ.mass;
            elseif(app.ZweifachButton.Value == 1)
                FZ.mass = 2 * FZ.mass;
            elseif(app.DreifachButton.Value == 1)
                FZ.mass = 3 * FZ.mass;
            end
            
           %SimulierteFahrzeuge = zeros(1,1);

            % erste Fahrt und letzte Fahrt in Anz. Sekunden
            t1 = app.StartTimeTextArea.Value;
            [Y, M, D, H, MN, S] = datevec(t1);
            t1 = H*3600 + MN*60+S;
            t2 = app.EndTimeTextArea.Value;
            [Y, M, D, H, MN, S] = datevec(t2);
            t2 = H*3600 + MN*60+S;

            Fz_Nr = 1;
            for t = t1:t2 % 21480:32520 % 21480:68700 %28500:32520 %21480:21481  %57360:68700
    
                for i = 1:length(Timetable.Start)
            
                    if (t == Timetable.Start(i))
                        disp(Timetable.Start(i))
                        % Bestimmung der Strecke laut Fahrplan
                        Str = Strecken.(string(Timetable.Strecke(i)));
                        % Bestimmung des Fahrzeugtyps laut Fahrplan
                        % FZ = Timetable.Fahrzeugtyp(i);
            
                        % Aufruf der Funktion für die Simulation der Zugfahrt
                        [Str_sim, Fz_sim, app.CompData] = Fahrtsimulation6_3(Str, FZ, const);
            
                        % aktuellen Zeitpunkt festellen
                        pos = find(sim_data(:, 1) == t);
            
                        % Daten zum richtigen Zeitpunkt in die Matrix schreiben
                        sim_data(pos:pos + length(Str_sim.Strecke) - 1, Fz_Nr + 1) = Fz_sim.Leistung;
                        % Es kann angegeben werden, welcher Parameter ausgelesen werden soll.
            
                        % Zeitpunkt an welchem Fahrzeug losfährt und ankommt
                        time = seconds(transpose((t):0.1:(t-0.1+length(Str_sim.Strecke)/10)));
                        time.Format = 'hh:mm:ss.S';
                        app.CompData.time = time;
                        Fz_Nr = Fz_Nr + 1;   % nächstes Fahrzeug
                        SimulierteFahrzeuge(i) = i;
                        StrName = "Fahrzeug" + i; % Name für Fahrzeug in Datenstruktur
                        app.CompleteData.(StrName) = app.CompData; % Simulierte Daten werden in Strucktur gespeichert
            
                    end
            
                end
    
            end
            disp(find(SimulierteFahrzeuge,1,'first'))
            disp(SimulierteFahrzeuge(end))
            app.SimFahrzeugeEditField.Value = find(SimulierteFahrzeuge,1,'first');
            app.letztesFahrzeugEditField.Value = SimulierteFahrzeuge(end);
         
            app.P_tot = sum(sim_data(:,2:end),2);

            app.start = find((sim_data(1:end,2)),1,'first');
            app.finish = find((sim_data(1:end,Fz_Nr)),1,'last');
            active = sim_data(app.start:app.finish, 1:end);
            app.timeP_tot = seconds((active(1:end,1)));
            app.timeP_tot.Format = 'hh:mm:ss.S';

            %plot(app.UIAxes3, CompData.Strecke, CompData.Leistung/1000)
            hold(app.UIAxes3,"on")
            plot(app.UIAxes3, app.timeP_tot, app.P_tot(app.start:app.finish,1)/1000)
            title(app.UIAxes3, 'Leistungsverlauf Fahrplansimulation zwischen Stadelhofen - Zürich HB - Hardbrücke')
            xlabel(app.UIAxes3, 'Zeit')
            ylabel(app.UIAxes3, 'Leistung [MW]')

            % pause(2)

            app.SimulationLamp.Color = 'g';
            app.EditField_2.Value = 'Simulation beendet';

        end

        % Value changed function: FahrzeugNrEditField
        function FahrzeugNrEditFieldValueChanged(app, event)
            value = app.FahrzeugNrEditField.Value;

            if(value > 0)
                if(value < app.SimFahrzeugeEditField.Value || value > app.letztesFahrzeugEditField.Value)
                    errordlg(strcat('gewähltes Fahrzeug wurde nicht simuliert. Bitte wählen sie ein Fahrzeug zwischen ', ...
                        sprintf(' %.0f',app.SimFahrzeugeEditField.Value), ' und ', sprintf(' %.0f',app.letztesFahrzeugEditField.Value),'!'), 'Eingabefehler!')
                end
                
                app.FahrzeugName = "Fahrzeug" + value;
                plot(app.UIAxes3,app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Leistung/1000)

                plot(app.UIAxes3_2, app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Leistung/1000)
                title(app.UIAxes3_2, "Leistungsverlauf " + app.FahrzeugName)
                xlabel(app.UIAxes3_2, 'Zeit')
                ylabel(app.UIAxes3_2, 'Leistung [MW]')

                app.UITable2.Data = app.CompleteData.(app.FahrzeugName); 
                app.UITable2.ColumnName = app.CompleteData.(app.FahrzeugName).Properties.VariableNames;
                
            end
        end

        % Value changed function: MittelwertCheckBox
        function MittelwertCheckBoxValueChanged(app, event)
            value = app.MittelwertCheckBox.Value;
            disp(value)

            if(value == 1)
                n = 1;
                for i = 1:3000:864000
                    if (i <= 4800)

                        P_tot8Min(1:4800,n)  = 0;

                    else
                        for k = 1:4800
                            P_tot8Min(k ,n) = app.P_tot(i-k);
                        end
                    end
                    n = n + 1;
                end

                P_5to8MinMean = mean(P_tot8Min)';

                P_5to8MinMean(:,2) = (1:length(P_5to8MinMean)) * 5;

                app.time = minutes(P_5to8MinMean(:,2));
                app.time.Format = 'hh:mm:ss';

                app.legendUI4(1,app.legendUI4Nr) = "Mittelwert (mit negativen Werten)";
                app.legendUI4Nr = app.legendUI4Nr + 1;

                hold(app.UIAxes4,"on")
                plot(app.UIAxes4, app.time, P_5to8MinMean(:,1)/1000)
                %legend(app.UIAxes4, app.legendUI4)
                legend(app.UIAxes4, app.legendUI4)
                title(app.UIAxes4, '8 Minuten Mittelwerte der Leistungsverläufe')
                xlabel(app.UIAxes4, 'Zeit')
                ylabel(app.UIAxes4, 'Leistung [MW]')
                
            else
                hold(app.UIAxes4,"off")
                cla(app.UIAxes4)
                app.legendUI4Nr = 1;
                app.legendUI4 = strings(1,4);
                app.MittelwertpositiveWerteCheckBox.Value = 0;
                app.MesswerteMontagCheckBox.Value = 0;
                app.MesswerteSonntagCheckBox.Value = 0;
            end
        end

        % Value changed function: MittelwertpositiveWerteCheckBox
        function MittelwertpositiveWerteCheckBoxValueChanged(app, event)
            value = app.MittelwertpositiveWerteCheckBox.Value;
            P_totPos = abs(app.P_tot);
            n = 1;
            if(value == 1)
                for i = 1:3000:864000
                    if (i <= 4800)

                        P_tot8MinPos(1:4800,n)  = 0;

                    else
                        for k = 1:4800
                            P_tot8MinPos(k ,n) = P_totPos(i-k);
                        end
                    end
                    n = n + 1;
                end

                P_5to8MinMeanPos = mean(P_tot8MinPos)';

                P_5to8MinMeanPos(:,2) = (1:length(P_5to8MinMeanPos)) * 5;

    
                time2 = minutes(P_5to8MinMeanPos(:,2));
                time2.Format = 'hh:mm:ss';
                app.legendUI4(1,app.legendUI4Nr) = "Mittelwert (absolut)";
                app.legendUI4Nr = app.legendUI4Nr + 1;
                hold(app.UIAxes4,"on")
                plot(app.UIAxes4, time2, P_5to8MinMeanPos(:,1)/1000)
                legend(app.UIAxes4, app.legendUI4)
                title(app.UIAxes4, '8 Minuten Mittelwerte der Leistungsverläufe')
                xlabel(app.UIAxes4, 'Zeit')
                ylabel(app.UIAxes4, 'Leistung [MW]')
            else
                hold(app.UIAxes4,"off")
                cla(app.UIAxes4)
                app.legendUI4Nr = 1;
                app.legendUI4 = strings(1,4);
                app.MittelwertCheckBox.Value = 0;
                app.MesswerteMontagCheckBox.Value = 0;
                app.MesswerteSonntagCheckBox.Value = 0;
            end
        end

        % Value changed function: MesswerteMontagCheckBox
        function MesswerteMontagCheckBoxValueChanged(app, event)
            value = app.MesswerteMontagCheckBox.Value;
            run Import_measurements.m;
            disp(size(app.time))
            disp(size(MeasMonday))
            if(value == 1)
                app.legendUI4(1,app.legendUI4Nr) = "Messwerte Montag";
                app.legendUI4Nr = app.legendUI4Nr + 1;
                hold(app.UIAxes4,"on")
                plot(app.UIAxes4, app.time,MeasMonday.P8minMittelMW(2:end))
                legend(app.UIAxes4, app.legendUI4)
                title(app.UIAxes4, '8 Minuten Mittelwerte der Leistungsverläufe')
                xlabel(app.UIAxes4, 'Zeit')
                ylabel(app.UIAxes4, 'Leistung [MW]')
            else
                hold(app.UIAxes4,"off")
                cla(app.UIAxes4)
                app.legendUI4Nr = 1;
                app.legendUI4 = strings(1,4);
                app.MittelwertCheckBox.Value = 0;
                app.MittelwertpositiveWerteCheckBox.Value = 0;
                app.MesswerteSonntagCheckBox.Value = 0;
            end
            
        end

        % Value changed function: MesswerteSonntagCheckBox
        function MesswerteSonntagCheckBoxValueChanged(app, event)
            value = app.MesswerteSonntagCheckBox.Value;
            run Import_measurements.m;
            disp(app.time)
            if(value == 1)
                app.legendUI4(1,app.legendUI4Nr) = "Messwerte Sonntag";
                app.legendUI4Nr = app.legendUI4Nr + 1;
                hold(app.UIAxes4,"on")
                size(app.time)
                size(MeasSunday.P8minMittelMW)
                plot(app.UIAxes4, app.time, MeasSunday.P8minMittelMW)
                legend(app.UIAxes4, app.legendUI4)
                title(app.UIAxes4, '8 Minuten Mittelwerte der Leistungsverläufe')
                xlabel(app.UIAxes4, 'Zeit')
                ylabel(app.UIAxes4, 'Leistung [MW]')
            else
                hold(app.UIAxes4,"off")
                cla(app.UIAxes4)
                app.legendUI4Nr = 1;
                app.legendUI4 = strings(1,4);
                app.MittelwertCheckBox.Value = 0;
                app.MittelwertpositiveWerteCheckBox.Value = 0;
                app.MesswerteMontagCheckBox.Value = 0;
            end
            
        end

        % Button pushed function: PlotWiderstndeButton
        function PlotWiderstndeButtonPushed(app, event)
            if(app.FahrzeugNrEditField.Value == 0)
                errordlg(strcat('gewähltes Fahrzeug wurde nicht simuliert. Bitte wählen sie ein Fahrzeug zwischen ', ...
                    sprintf(' %.0f',app.SimFahrzeugeEditField.Value), ' und ', sprintf(' %.0f',app.letztesFahrzeugEditField.Value),'!'), 'Eingabefehler!')
            else
                plot(app.UIAxes5, app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Laufwiderstand, ...
                    app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Steigungswiderstand, ...
                    app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Beschleunigungswiderstand, ...
                    app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Gesamtwiderstand, ...
                    app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).Zugkraft)
                title(app.UIAxes5, "Widerstandsverlauf " + app.FahrzeugName)
                xlabel(app.UIAxes5, 'Zeit')
                ylabel(app.UIAxes5, 'Kraft [kN]')
                legend(app.UIAxes5,["Laufwiderstand" "Steigung" "Beschleunigung" "Gesamtwid." "Zugkraft"])
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % disp(selectedNodes.Text)
            hold(app.UIAxes6, "on")
            if(app.LinksButton.Value == 1)
                yyaxis(app.UIAxes6, "left")
                if(app.GeschwindigkeitButton.Value == 1)
                    app.legendUI6.left(1,app.legendUI6Nrleft) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrleft = app.legendUI6Nrleft + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).(app.GeschwindigkeitButton.Text), app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Geschwindigkeit [km/h]')
                elseif(app.ZeitButton_2.Value == 1)
                    app.legendUI6.left(1,app.legendUI6Nrleft) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrleft = app.legendUI6Nrleft + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Uhrzeit')
                elseif(app.StreckeButton_2.Value == 1)
                    app.legendUI6.left(1,app.legendUI6Nrleft) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrleft = app.legendUI6Nrleft + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).(app.StreckeButton_2.Text), app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Strecke [m]')
                end
            elseif(app.RechtsButton.Value == 1)
                yyaxis(app.UIAxes6, "right")
                if(app.GeschwindigkeitButton.Value == 1)
                    app.legendUI6.right(1,app.legendUI6Nrright) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrright = app.legendUI6Nrright + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).(app.GeschwindigkeitButton.Text), app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Geschwindigkeit [km/h]')
                elseif(app.ZeitButton_2.Value == 1)
                    app.legendUI6.right(1,app.legendUI6Nrright) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrright = app.legendUI6Nrright + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).time, app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Uhrzeit')
                elseif(app.StreckeButton_2.Value == 1)
                    app.legendUI6.right(1,app.legendUI6Nrright) = selectedNodes.Text + " " + app.units.(selectedNodes.Text);
                    app.legendUI6Nrright = app.legendUI6Nrright + 1;
                    plot(app.UIAxes6, app.CompleteData.(app.FahrzeugName).(app.StreckeButton_2.Text), app.CompleteData.(app.FahrzeugName).(selectedNodes.Text))
                    xlabel(app.UIAxes6, 'Strecke [m]')
                end                
            end
            
            legend(app.UIAxes6, [app.legendUI6.left app.legendUI6.right]);           
            grid(app.UIAxes6, "on")
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
            hold(app.UIAxes6, "off")
            cla(app.UIAxes6, "reset")
            app.legendUI6 = struct("left", strings(1,1), "right", strings(1,1));
            app.legendUI6Nrright = 1;
            app.legendUI6Nrleft = 1;
        end

        % Button pushed function: SpeichereDatenButton
        function SpeichereDatenButtonPushed(app, event)
            if(app.FahrplanDropDown.Value == "Montag")
                SpeicherTag = "Montag";
            elseif(app.FahrplanDropDown.Value == "Sonntag")
                SpeicherTag = "Sonntag";
            end

            app.SimData.(SpeicherTag) = app.CompleteData;
            app.Leistungsverlauf.(SpeicherTag) = app.P_tot;

        end

        % Button pushed function: VergleichSonntagundMontagButton
        function VergleichSonntagundMontagButtonPushed(app, event)
            disp(size(app.timeP_tot))
            disp(size(app.Leistungsverlauf))
            hold(app.UIAxes7, "on")
            plot(app.UIAxes7, app.timeP_tot, app.Leistungsverlauf.Montag(app.start:app.finish,1)/1000)
            plot(app.UIAxes7, app.timeP_tot, app.Leistungsverlauf.Sonntag(app.start:app.finish,1)/1000)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FahrtsimulationTab = uitab(app.TabGroup);
            app.FahrtsimulationTab.Title = 'Fahrtsimulation';
            app.UIAxes = uiaxes(app.FahrtsimulationTab);
            ylabel(app.UIAxes, 'Leistung [MW]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.FahrtsimulationTab);
            title(app.UIAxes2, 'Title')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FahrzeugtypDropDownLabel = uilabel(app.FahrtsimulationTab);
            app.FahrzeugtypDropDownLabel.Text = 'Fahrzeugtyp';
            app.FahrzeugtypDropDown = uidropdown(app.FahrtsimulationTab);
            app.FahrzeugtypDropDown.Items = {'KISS', 'RE450'};
            app.FahrzeugtypDropDown.Value = 'KISS';
            app.StreckeDropDownLabel = uilabel(app.FahrtsimulationTab);
            app.StreckeDropDownLabel.Text = 'Strecke';
            app.StreckeDropDown = uidropdown(app.FahrtsimulationTab);
            app.StreckeDropDown.Items = {'ZH_HB', 'HB_ZH', 'ST_ZH', 'ZH_ST'};
            app.StreckeDropDown.Value = 'ZH_HB';
            app.SimulationButton = uibutton(app.FahrtsimulationTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.EditField = uieditfield(app.FahrtsimulationTab, 'text');
            app.EditField.Value = 'Streckeninformationen';
            app.UITable = uitable(app.FahrtsimulationTab);
            app.UITable.ColumnName = {'Länge'; 'V_max'; 'Min. Steigung'; 'Max. Steigung'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.resetPlotsButton = uibutton(app.FahrtsimulationTab, 'push');
            app.resetPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @resetPlotsButtonPushed, true);
            app.resetPlotsButton.Text = 'reset Plots';
            app.LeistungsverlaufButtonGroup = uibuttongroup(app.FahrtsimulationTab);
            app.LeistungsverlaufButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeistungsverlaufButtonGroupSelectionChanged, true);
            app.LeistungsverlaufButtonGroup.Title = 'Leistungsverlauf';
            app.ZeitButton = uiradiobutton(app.LeistungsverlaufButtonGroup);
            app.ZeitButton.Text = 'Zeit';
            app.ZeitButton.Value = true;
            app.StreckeButton = uiradiobutton(app.LeistungsverlaufButtonGroup);
            app.StreckeButton.Text = 'Strecke';
            app.FahrplansimTab = uitab(app.TabGroup);
            app.FahrplansimTab.Title = 'Fahrplansim.';
            app.StartFahrplansimultationButton = uibutton(app.FahrplansimTab, 'push');
            app.StartFahrplansimultationButton.ButtonPushedFcn = createCallbackFcn(app, @StartFahrplansimultationButtonPushed, true);
            app.StartFahrplansimultationButton.Text = 'Start Fahrplansimultation';
            app.SimulationLamp = uilamp(app.FahrplansimTab);
            app.FahrzeugtypDropDown_2Label = uilabel(app.FahrplansimTab);
            app.FahrzeugtypDropDown_2Label.Text = 'Fahrzeugtyp';
            app.FahrzeugtypDropDown_2 = uidropdown(app.FahrplansimTab);
            app.FahrzeugtypDropDown_2.Items = {'KISS', 'RE450'};
            app.FahrzeugtypDropDown_2.Value = 'KISS';
            app.StartTimeTextAreaLabel = uilabel(app.FahrplansimTab);
            app.StartTimeTextAreaLabel.Text = 'Start Time';
            app.StartTimeTextArea = uitextarea(app.FahrplansimTab);
            app.EndTimeTextAreaLabel = uilabel(app.FahrplansimTab);
            app.EndTimeTextAreaLabel.Text = 'End Time';
            app.EndTimeTextArea = uitextarea(app.FahrplansimTab);
            app.EditField_2 = uieditfield(app.FahrplansimTab, 'text');
            app.EditField_2.Editable = 'off';
            app.StreckendatenDropDownLabel = uilabel(app.FahrplansimTab);
            app.StreckendatenDropDownLabel.Text = 'Streckendaten';
            app.StreckendatenDropDown = uidropdown(app.FahrplansimTab);
            app.StreckendatenDropDown.Items = {'ZLR_Datei', 'Json_Datei'};
            app.StreckendatenDropDown.Value = 'ZLR_Datei';
            app.FahrzeugNrEditFieldLabel = uilabel(app.FahrplansimTab);
            app.FahrzeugNrEditFieldLabel.Text = 'Fahrzeug Nr.';
            app.FahrzeugNrEditField = uieditfield(app.FahrplansimTab, 'numeric');
            app.FahrzeugNrEditField.ValueChangedFcn = createCallbackFcn(app, @FahrzeugNrEditFieldValueChanged, true);
            app.SimFahrzeugeEditFieldLabel = uilabel(app.FahrplansimTab);
            app.SimFahrzeugeEditFieldLabel.Text = 'Sim. Fahrzeuge';
            app.SimFahrzeugeEditField = uieditfield(app.FahrplansimTab, 'numeric');
            app.SimFahrzeugeEditField.Editable = 'off';
            app.bisLabel = uilabel(app.FahrplansimTab);
            app.bisLabel.Text = 'bis';
            app.letztesFahrzeugEditField = uieditfield(app.FahrplansimTab, 'numeric');
            app.letztesFahrzeugEditField.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.FahrplansimTab);
            app.LeistungsverlaufTab = uitab(app.TabGroup2);
            app.LeistungsverlaufTab.Title = 'Leistungsverlauf';
            app.UIAxes3 = uiaxes(app.LeistungsverlaufTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.LeistungsverlaufTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.MittelwerteTab = uitab(app.TabGroup2);
            app.MittelwerteTab.Title = 'Mittelwerte';
            app.UIAxes4 = uiaxes(app.MittelwerteTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.MittelwertCheckBox = uicheckbox(app.MittelwerteTab);
            app.MittelwertCheckBox.ValueChangedFcn = createCallbackFcn(app, @MittelwertCheckBoxValueChanged, true);
            app.MittelwertCheckBox.Text = 'Mittelwert';
            app.MittelwertpositiveWerteCheckBox = uicheckbox(app.MittelwerteTab);
            app.MittelwertpositiveWerteCheckBox.ValueChangedFcn = createCallbackFcn(app, @MittelwertpositiveWerteCheckBoxValueChanged, true);
            app.MittelwertpositiveWerteCheckBox.Text = 'Mittelwert positive Werte';
            app.MesswerteMontagCheckBox = uicheckbox(app.MittelwerteTab);
            app.MesswerteMontagCheckBox.ValueChangedFcn = createCallbackFcn(app, @MesswerteMontagCheckBoxValueChanged, true);
            app.MesswerteMontagCheckBox.Text = 'Messwerte Montag';
            app.MesswerteSonntagCheckBox = uicheckbox(app.MittelwerteTab);
            app.MesswerteSonntagCheckBox.ValueChangedFcn = createCallbackFcn(app, @MesswerteSonntagCheckBoxValueChanged, true);
            app.MesswerteSonntagCheckBox.Text = 'Messwerte Sonntag';
            app.WiderstndeTab = uitab(app.TabGroup2);
            app.WiderstndeTab.Title = 'Widerstände';
            app.UIAxes5 = uiaxes(app.WiderstndeTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.PlotWiderstndeButton = uibutton(app.WiderstndeTab, 'push');
            app.PlotWiderstndeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWiderstndeButtonPushed, true);
            app.PlotWiderstndeButton.Text = 'Plot Widerstände';
            app.SimDataTab = uitab(app.TabGroup2);
            app.SimDataTab.Title = 'SimData';
            app.UITable2 = uitable(app.SimDataTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.BenutzerdefTab = uitab(app.TabGroup2);
            app.BenutzerdefTab.Title = 'Benutzerdef.';
            app.UIAxes6 = uiaxes(app.BenutzerdefTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.XAchseButtonGroup = uibuttongroup(app.BenutzerdefTab);
            app.XAchseButtonGroup.Title = 'X-Achse';
            app.ZeitButton_2 = uiradiobutton(app.XAchseButtonGroup);
            app.ZeitButton_2.Text = 'Zeit';
            app.ZeitButton_2.Value = true;
            app.StreckeButton_2 = uiradiobutton(app.XAchseButtonGroup);
            app.StreckeButton_2.Text = 'Strecke';
            app.GeschwindigkeitButton = uiradiobutton(app.XAchseButtonGroup);
            app.GeschwindigkeitButton.Text = 'Geschwindigkeit';
            app.ResetPlotButton = uibutton(app.BenutzerdefTab, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = 'Reset Plot';
            app.Tree = uitree(app.BenutzerdefTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FahrzeugparameterNode = uitreenode(app.Tree);
            app.FahrzeugparameterNode.Text = 'Fahrzeugparameter';
            app.GeschwindigkeitNode = uitreenode(app.FahrzeugparameterNode);
            app.GeschwindigkeitNode.Text = 'Geschwindigkeit';
            app.BeschleunigungNode = uitreenode(app.FahrzeugparameterNode);
            app.BeschleunigungNode.Text = 'Beschleunigung';
            app.ZugkraftNode = uitreenode(app.FahrzeugparameterNode);
            app.ZugkraftNode.Text = 'Zugkraft';
            app.LeistungNode = uitreenode(app.FahrzeugparameterNode);
            app.LeistungNode.Text = 'Leistung';
            app.AdhaesionskraftNode = uitreenode(app.FahrzeugparameterNode);
            app.AdhaesionskraftNode.Text = 'Adhaesionskraft';
            app.StreckenparameterNode = uitreenode(app.Tree);
            app.StreckenparameterNode.Text = 'Streckenparameter';
            app.StreckeNode = uitreenode(app.StreckenparameterNode);
            app.StreckeNode.Text = 'Strecke';
            app.ReststreckeNode = uitreenode(app.StreckenparameterNode);
            app.ReststreckeNode.Text = 'Reststrecke';
            app.BremsstreckeNode = uitreenode(app.StreckenparameterNode);
            app.BremsstreckeNode.Text = 'Bremsstrecke';
            app.SteigungNode = uitreenode(app.StreckenparameterNode);
            app.SteigungNode.Text = 'Steigung';
            app.MaximalGeschwNode = uitreenode(app.StreckenparameterNode);
            app.MaximalGeschwNode.Text = 'MaximalGeschw';
            app.LaufwiderstandNode = uitreenode(app.StreckenparameterNode);
            app.LaufwiderstandNode.Text = 'Laufwiderstand';
            app.SteigungswiderstandNode = uitreenode(app.StreckenparameterNode);
            app.SteigungswiderstandNode.Text = 'Steigungswiderstand';
            app.BeschleunigungswiderstandNode = uitreenode(app.StreckenparameterNode);
            app.BeschleunigungswiderstandNode.Text = 'Beschleunigungswiderstand';
            app.GesamtwiderstandNode = uitreenode(app.StreckenparameterNode);
            app.GesamtwiderstandNode.Text = 'Gesamtwiderstand';
            app.YAchseButtonGroup = uibuttongroup(app.BenutzerdefTab);
            app.YAchseButtonGroup.Title = 'Y-Achse';
            app.LinksButton = uiradiobutton(app.YAchseButtonGroup);
            app.LinksButton.Text = 'Links';
            app.LinksButton.Value = true;
            app.RechtsButton = uiradiobutton(app.YAchseButtonGroup);
            app.RechtsButton.Text = 'Rechts';
            app.VergleichSonntagMontagTab = uitab(app.TabGroup2);
            app.VergleichSonntagMontagTab.Title = 'Vergleich Sonntag Montag';
            app.UIAxes7 = uiaxes(app.VergleichSonntagMontagTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.VergleichSonntagundMontagButton = uibutton(app.VergleichSonntagMontagTab, 'push');
            app.VergleichSonntagundMontagButton.ButtonPushedFcn = createCallbackFcn(app, @VergleichSonntagundMontagButtonPushed, true);
            app.VergleichSonntagundMontagButton.Text = 'Vergleich Sonntag und Montag';
            app.BelegungButtonGroup = uibuttongroup(app.FahrplansimTab);
            app.BelegungButtonGroup.Title = 'Belegung';
            app.EinfachButton = uiradiobutton(app.BelegungButtonGroup);
            app.EinfachButton.Text = 'Einfach';
            app.EinfachButton.Value = true;
            app.ZweifachButton = uiradiobutton(app.BelegungButtonGroup);
            app.ZweifachButton.Text = 'Zweifach';
            app.DreifachButton = uiradiobutton(app.BelegungButtonGroup);
            app.DreifachButton.Text = 'Dreifach';
            app.FahrplanDropDownLabel = uilabel(app.FahrplansimTab);
            app.FahrplanDropDownLabel.Text = 'Fahrplan';
            app.FahrplanDropDown = uidropdown(app.FahrplansimTab);
            app.FahrplanDropDown.Items = {'Montag', 'Sonntag'};
            app.FahrplanDropDown.Value = 'Montag';
            app.SpeichereDatenButton = uibutton(app.FahrplansimTab, 'push');
            app.SpeichereDatenButton.ButtonPushedFcn = createCallbackFcn(app, @SpeichereDatenButtonPushed, true);
            app.SpeichereDatenButton.Text = 'Speichere Daten';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FahrtsimulationAppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end